classdef rotation_matrix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        phiSliderLabel    matlab.ui.control.Label
        phiSlider         matlab.ui.control.Slider
        thetaSliderLabel  matlab.ui.control.Label
        thetaSlider       matlab.ui.control.Slider
        psiSliderLabel    matlab.ui.control.Label
        psiSlider         matlab.ui.control.Slider
    end

    
    properties (Access = private)
        body_frame % Description
        earth_frame 
        bf_x = 0
        bf_y = 0
        bf_z = 0
        bf_phi = 0
        bf_theta = 0
        bf_psi = 0
    end
    methods (Access = private)
        % 座標系の作成
        % 全ての引数を0にした場合、ローカル座標系となる
        function frame_state = create_frame(app,x,y,z,phi,theta,psi)
            % 座標系のプロット
            frame_state.x = quiver3(app.UIAxes,0,0,0,0,0,0,0,'Color','red','LineWidth',2);
            frame_state.y = quiver3(app.UIAxes,0,0,0,0,0,0,0,'Color','blue','LineWidth',2);
            frame_state.z = quiver3(app.UIAxes,0,0,0,0,0,0,0,'Color','green','LineWidth',2);
            update_frame(app,frame_state,x,y,z,phi,theta,psi);
        end
    
        % 機体固定座標系の更新
        % ローカル座標系(地図座標系)から見た機体固定系の座標と姿勢を更新する
        function update_frame(app,frame_state,x,y,z,phi,theta,psi)
            % ローカル座標系の定義
            ex = [1;0;0];
            ey = [0;1;0];
            ez = [0;0;1];
            % 三角関数の定義
            sin_phi = sin(phi);
            cos_phi = cos(phi);
            sin_theta = sin(theta);
            cos_theta = cos(theta);
            sin_psi = sin(psi);
            cos_psi = cos(psi);
            % 回転行列
            Rx = [1 0 0;0 cos_phi -sin_phi;0 sin_phi cos_phi];
            Ry = [cos_theta 0 sin_theta;0 1 0;-sin_theta 0 cos_theta];
            Rz = [cos_psi -sin_psi 0;sin_psi cos_psi 0;0 0 1];
            % 機体固定系の算出
            bx = Rx*Ry*Rz*ex;
            by = Rx*Ry*Rz*ey;
            bz = Rx*Ry*Rz*ez;
            % 座標系ベクトルの設定
            set(frame_state.x,'UData',bx(1),'VData',bx(2),'WData',bx(3),'XData',x,'YData',y,'ZData',z);
            set(frame_state.y,'UData',by(1),'VData',by(2),'WData',by(3),'XData',x,'YData',y,'ZData',z);
            set(frame_state.z,'UData',bz(1),'VData',bz(2),'WData',bz(3),'XData',x,'YData',y,'ZData',z);
        end
        function update_body_frame(app)
            % ローカル座標系の定義
            ex = [1;0;0];
            ey = [0;1;0];
            ez = [0;0;1];
            % 三角関数の定義
            sin_phi = sin(app.bf_phi);
            cos_phi = cos(app.bf_phi);
            sin_theta = sin(app.bf_theta);
            cos_theta = cos(app.bf_theta);
            sin_psi = sin(app.bf_psi);
            cos_psi = cos(app.bf_psi);
            % 回転行列
            Rx = [1 0 0;0 cos_phi -sin_phi;0 sin_phi cos_phi];
            Ry = [cos_theta 0 sin_theta;0 1 0;-sin_theta 0 cos_theta];
            Rz = [cos_psi -sin_psi 0;sin_psi cos_psi 0;0 0 1];
            % 機体固定系の算出
            bx = Rx*Ry*Rz*ex;
            by = Rx*Ry*Rz*ey;
            bz = Rx*Ry*Rz*ez;
            % 座標系ベクトルの設定
            set(app.body_frame.x,'UData',bx(1),'VData',bx(2),'WData',bx(3),'XData',app.bf_x,'YData',app.bf_y,'ZData',app.bf_z);
            set(app.body_frame.y,'UData',by(1),'VData',by(2),'WData',by(3),'XData',app.bf_x,'YData',app.bf_y,'ZData',app.bf_z);
            set(app.body_frame.z,'UData',bz(1),'VData',bz(2),'WData',bz(3),'XData',app.bf_x,'YData',app.bf_y,'ZData',app.bf_z);
        end
        function frame_state = test_create(app)
            frame_state.x = quiver3(app.UIAxes,0,0,0,0,0,0,0,'Color','red');
%             frame_state.y = quiver3(app.UIAxes,0,0,0,0,0,0,0,'Color','blue');
%             frame_state.z = quiver3(app.UIAxes,0,0,0,0,0,0,0,'Color','green');
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.UIAxes,'on');
            rotate3d(app.UIAxes,'on');
            xlabel(app.UIAxes,'x [m]','FontName','arial','FontSize',10)
            ylabel(app.UIAxes,'y [m]','FontName','arial','FontSize',10)
            zlabel(app.UIAxes,'z [m]','FontName','arial','FontSize',10)
            set(app.UIAxes,...
                'YDir','reverse',...
                'ZDir','reverse',...
                'XLim',[-4 4],...
                'YLim',[-4 4],...
                'ZLim',[-4 4]...
                );
            app.earth_frame = create_frame(app,0,0,0,0*pi/180,0*pi/180,0*pi/180);
            app.body_frame = create_frame(app,0,0,0,0*pi/180,0*pi/180,0*pi/180);
        end

        % Value changing function: phiSlider
        function phiSliderValueChanging(app, event)
            app.bf_phi = event.Value*pi/180;
            update_body_frame(app);
        end

        % Value changing function: thetaSlider
        function thetaSliderValueChanging(app, event)
            app.bf_theta = event.Value*pi/180;
            update_body_frame(app);
        end

        % Value changing function: psiSlider
        function psiSliderValueChanging(app, event)
            app.bf_psi = event.Value*pi/180;
            update_body_frame(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.phiSliderLabel = uilabel(app.UIFigure);
            app.phiSliderLabel.Text = 'phi';
            app.phiSlider = uislider(app.UIFigure);
            app.phiSlider.Limits = [0 180];
            app.phiSlider.ValueChangingFcn = createCallbackFcn(app, @phiSliderValueChanging, true);
            app.thetaSliderLabel = uilabel(app.UIFigure);
            app.thetaSliderLabel.Text = 'theta';
            app.thetaSlider = uislider(app.UIFigure);
            app.thetaSlider.Limits = [0 180];
            app.thetaSlider.ValueChangingFcn = createCallbackFcn(app, @thetaSliderValueChanging, true);
            app.psiSliderLabel = uilabel(app.UIFigure);
            app.psiSliderLabel.Text = 'psi';
            app.psiSlider = uislider(app.UIFigure);
            app.psiSlider.Limits = [0 180];
            app.psiSlider.ValueChangingFcn = createCallbackFcn(app, @psiSliderValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = rotation_matrix

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end